classdef Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        SingleTargetPanel            matlab.ui.container.Panel
        DistanceEditFieldLabel       matlab.ui.control.Label
        DistanceEditField            matlab.ui.control.NumericEditField
        Plot1Button                  matlab.ui.control.Button
        MultipleTargetsPanel         matlab.ui.container.Panel
        Target1EditFieldLabel        matlab.ui.control.Label
        Target1EditField             matlab.ui.control.NumericEditField
        Target2EditFieldLabel        matlab.ui.control.Label
        Target2EditField             matlab.ui.control.NumericEditField
        Plot2Button                  matlab.ui.control.Button
        PartiallyCoherentRadarLabel  matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
         cm=zeros(1,301); % Description
         Cm=zeros(1,301);
%          targ;
    end


    methods (Access = private)

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
         
        end

        % Value changed function: DistanceEditField
        function DistanceEditFieldValueChanged(app, event)
            value = app.DistanceEditField.Value;
                M=300;   % M+1 coherence lengths 
            N=15;  % N+1 phase jumps in each coherence window <=8
            Fc=6e9; % carrier frequency
            Fs=16*Fc; % sampling Frequency
            c=3e8; % speed of light
            init_cycles=2; % no. of cycles in 1st coherence window, per phase length
            incr_cycles=1; % no. of  cycles to be  added to each coherence length, per phase length
            phi=(-pi+(2*pi/((N+1)*(M+1))):(2*pi/((N+1)*(M+1))):pi); % uniform phase jumps
            Tx=[];
            corr_lengths=[];
            %% Transmission Signal Synthesis
            for i=1:1:M+1
    for j=1:1:N+1
        t=0:1/Fs:(init_cycles/Fc+(i-1)*incr_cycles/Fc)-(1/Fs);
        Tx=horzcat(Tx,cos(2*pi*Fc*t+phi(j+(i-1)*(N+1))));
    end
    corr_lengths(i)=(N+1)*(length(t)); % coherence lengths 
            end
            targ= app.DistanceEditField.Value;
            D= (targ*2*Fs)/c; % corresponds to 250th coherence lengths 
            A= length(Tx);
            Rx=[Tx(A-D:A) Tx(1:A-(D+1))];
            
            
            app.Cm=zeros(1,M+1);
            for i=1:1:M+1
    if i==1
        app.Cm(i)=mean(Tx(1:corr_lengths(i)).*Rx(1:corr_lengths(i)));
    else
       app.Cm(i)=mean(Tx(corr_lengths(i-1)+1:corr_lengths(i)).*Rx(corr_lengths(i-1)+1:corr_lengths(i)));
    end
            end
            
            
        end

        % Button pushed function: Plot1Button
        function Plot1ButtonPushed(app, event)
%             ax= uiaxes;
%             scl= 2.5;
%             xt = get(gca, ax.XTick);                                 % 'XTick' Values
%             set(gca, ax.XTick, xt, ax.XTickLabel, xt/scl)
            plot(app.UIAxes, app.Cm)
               plot(app.UIAxes2, diff(app.Cm))
        end

        % Button pushed function: Plot2Button
        function Plot2ButtonPushed(app, event)
            plot(app.UIAxes, app.cm)
            plot(app.UIAxes2, diff(app.cm))
        end

        % Value changed function: Target2EditField
        function Target2EditFieldValueChanged(app, event)
            value = app.Target2EditField.Value;
        
            M=300;   % M+1 coherence lengths 
            N=15;  % N+1 phase jumps in each coherence window <=8
            Fc=6e9; % carrier frequency
            Fs=16*Fc; % sampling Frequency
            c=3e8; % speed of light
            init_cycles=2; % no. of cycles in 1st coherence window, per phase length
            incr_cycles=1; % no. of  cycles to be  added to each coherence length, per phase length
            phi=(-pi+(2*pi/((N+1)*(M+1))):(2*pi/((N+1)*(M+1))):pi); % uniform phase jumps
            Tx=[];
            corr_lengths=[];
            %% Transmission Signal Synthesis
            for i=1:1:M+1
    for j=1:1:N+1
        t=0:1/Fs:(init_cycles/Fc+(i-1)*incr_cycles/Fc)-(1/Fs);
        Tx=horzcat(Tx,cos(2*pi*Fc*t+phi(j+(i-1)*(N+1))));
    end
    corr_lengths(i)=(N+1)*(length(t)); % coherence lengths 
            end
            Targ0=app.Target1EditField.Value;
            D0=round((Targ0*2*Fs)/c); % in terms of coherence lengths
            Targ1=value;
           D1=round((Targ1*2*Fs)/c); % in terms of coherence lengths
            A= length(Tx);
            Rx1=[Tx(A-D0:A) Tx(1:(A-(D0+1)))]; % reflection of first target seperately
            Rx2=[Tx(A-D1:A) Tx(1:(A-(D1+1)))]; % reflection of second target seperately
            RX=Rx1+Rx2; % combined reflections of 1st and 2nd target
            % Correlation at coherence lengths
            Cm1=zeros(1,M+1);
            Cm2=zeros(1,M+1);
            %             cm=zeros(1,M+1);
            for i=1:1:M+1
    if i==1
        Cm1(i)=mean(Tx(1:corr_lengths(i)).*Rx1(1:corr_lengths(i)));
        Cm2(i)=mean(Tx(1:corr_lengths(i)).*Rx2(1:corr_lengths(i)));
        app.cm(i)=mean(Tx(1:corr_lengths(i)).*RX(1:corr_lengths(i)));
    else
        Cm1(i)=mean(Tx(corr_lengths(i-1)+1:corr_lengths(i)).*Rx1(corr_lengths(i-1)+1:corr_lengths(i)));
        Cm2(i)=mean(Tx(corr_lengths(i-1)+1:corr_lengths(i)).*Rx2(corr_lengths(i-1)+1:corr_lengths(i)));
        app.cm(i)=mean(Tx(corr_lengths(i-1)+1:corr_lengths(i)).*RX(corr_lengths(i-1)+1:corr_lengths(i)));
    end
    
            end
            %             plot(app.UIAxes,app.cm)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cross Correlation')
            xlabel(app.UIAxes, 'Length')
            ylabel(app.UIAxes, 'Correlation')
            app.SingleTargetPanel = uipanel(app.UIFigure);
            app.SingleTargetPanel.Title = 'Single Target';
            app.DistanceEditFieldLabel = uilabel(app.SingleTargetPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.SingleTargetPanel, 'numeric');
            app.DistanceEditField.ValueChangedFcn = createCallbackFcn(app, @DistanceEditFieldValueChanged, true);
            app.Plot1Button = uibutton(app.SingleTargetPanel, 'push');
            app.Plot1Button.ButtonPushedFcn = createCallbackFcn(app, @Plot1ButtonPushed, true);
            app.Plot1Button.Text = 'Plot1';
            app.MultipleTargetsPanel = uipanel(app.UIFigure);
            app.MultipleTargetsPanel.Title = 'Multiple Targets';
            app.Target1EditFieldLabel = uilabel(app.MultipleTargetsPanel);
            app.Target1EditFieldLabel.Text = 'Target 1';
            app.Target1EditField = uieditfield(app.MultipleTargetsPanel, 'numeric');
            app.Target2EditFieldLabel = uilabel(app.MultipleTargetsPanel);
            app.Target2EditFieldLabel.Text = 'Target 2';
            app.Target2EditField = uieditfield(app.MultipleTargetsPanel, 'numeric');
            app.Target2EditField.ValueChangedFcn = createCallbackFcn(app, @Target2EditFieldValueChanged, true);
            app.Plot2Button = uibutton(app.MultipleTargetsPanel, 'push');
            app.Plot2Button.ButtonPushedFcn = createCallbackFcn(app, @Plot2ButtonPushed, true);
            app.Plot2Button.Text = 'Plot2';
            app.PartiallyCoherentRadarLabel = uilabel(app.UIFigure);
            app.PartiallyCoherentRadarLabel.Text = 'Partially Coherent Radar';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Derivative of Cross Correlation')
            xlabel(app.UIAxes2, 'Length')
            ylabel(app.UIAxes2, 'Derivative of correlation')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Final

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end